classdef app3_1_mod1_pupil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        holaCheckBox           matlab.ui.control.CheckBox
        IniciarButton          matlab.ui.control.Button
        numeroEditField        matlab.ui.control.NumericEditField
        TiempoSlider           matlab.ui.control.Slider
        TiempoSliderLabel      matlab.ui.control.Label
        SeleccionarButton      matlab.ui.control.Button
        CerrarButton           matlab.ui.control.Button
        ListBox_2              matlab.ui.control.ListBox
        CargarButton           matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
        TextArea               matlab.ui.control.TextArea
        AbrirButton            matlab.ui.control.Button
        EditField              matlab.ui.control.EditField
        SelecciondedatosLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = public)
         directorio3_1 = '/home/osvaldo13576/Documentos/Servicio/pupil_data/2022_06_03/000'
         archivo
         datos_csv% Variables.....
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = app.directorio3_1;
            app.EditField.Editable = 0;
            app.ListBox.Enable = 0;
            app.ListBox_2.Enable = 0;
            app.CargarButton.Enable = 0;
            app.TextArea.Value = "Esperando archivos.";
            app.TiempoSlider.Limits = [1,999];
            app.TiempoSlider.Enable = 0;
            app.UIAxes.Visible = 1;
            app.holaCheckBox.Value =1;
        end

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
            % establecemos el valor del directorio
            directorio = uigetdir;
            if directorio == 0
                app.EditField.Value = app.directorio3_1;
            else
                app.EditField.Value = directorio;
            end
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            %dado un directorio listamos las carpetas precentes en e'l
            %verificamos si es un direc vacio
            if is_an_empty(app.EditField.Value) == 1
                app.TextArea.Value = "Directorio vacio.";
            else
                 %buscamos la existencia del archivo info.player.json
                 %para determinar si es un directorio valido
                 if isarchivo(app.EditField.Value,'info.player.json') == 1 && ...
                    isafolder(app.EditField.Value,'exports') == 1
                    directorio2 = fullfile(app.EditField.Value,'exports');
                    
                    if is_an_empty(directorio2) == 0
                        datosjson = readjson(fullfile(app.EditField.Value,'info.player.json'));
                        duracion_exp_min = datosjson.duration_s/60;
                        duracion_exp_min_char = sprintf('%.2f',duracion_exp_min);
                        start_time_synced_s = datosjson.start_time_synced_s;
                        start_time_system_s = datosjson.start_time_system_s;
                        fecha = convert_timestamp(start_time_system_s-start_time_synced_s);
                        app.TextArea.Value = "Archivo info.player.json entontrado."+newline+...
                        "Directorio exports no vacio, carpetas cargadas."+...
                        newline+"Duracion de la grabacion: "+convertCharsToStrings(duracion_exp_min_char)+" minutos"...
                        +newline+"Inicio del experimento: " + convertCharsToStrings(fecha);
                        app.ListBox.Items = listaFolders(directorio2);
                        app.ListBox.Enable = 1; 

                    else
                        app.TextArea.Value = "Directorio exports vacio";
                        app.ListBox.Enable = 0;
                    end
                 else
                     app.ListBox.Enable = 0;
                     app.TextArea.Value = "Directorio incompatible";

                end
                    
            end
           
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.TiempoSlider.Value = 1;
            directorio = fullfile(app.EditField.Value,'exports',app.ListBox.Value);
            app.ListBox_2.Items = listaArchivos(directorio,'*.csv');
            if length(app.ListBox_2.Items)>1
                app.ListBox_2.Enable = 1;
                if isarchivo(directorio,'export_info.csv') == 1
                    app.TiempoSlider.Enable = 1;
                    numdatos=get_exportinfo(fullfile(directorio,'export_info.csv'))+1;
                    app.TiempoSlider.Limits = [1,numdatos];
                    app.CargarButton.Enable = 1;
                    app.archivo = app.ListBox_2.Value;
                else
                    app.TiempoSlider.Enable = 0;
                end
            else
                app.TiempoSlider.Enable = 0;
                app.ListBox_2.Enable = 0;
            end
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            app.archivo = app.ListBox_2.Value; 
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            directorio_csv = fullfile(app.EditField.Value,'exports',app.ListBox.Value,...
                app.archivo);
            app.TextArea.Value = "Archivo cargado: " + convertCharsToStrings(directorio_csv);
            app.datos_csv = readtable(directorio_csv);
        end

        % Value changed function: TiempoSlider
        function TiempoSliderValueChanged(app, event)
            %value = app.TiempoSlider.Value;
            valor = round(app.TiempoSlider.Value);
            app.TiempoSlider.Value = valor;
            app.numeroEditField.Value = app.TiempoSlider.Value;
            
            r = app.datos_csv.diameter(app.TiempoSlider.Value)/2;
            x = app.datos_csv.norm_pos_x(app.TiempoSlider.Value);
            y = app.datos_csv.norm_pos_y(app.TiempoSlider.Value);
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x;
            yunit = r * sin(th) + y;
            plot(app.UIAxes,xunit, yunit);
        end

        % Value changed function: holaCheckBox
        function holaCheckBoxValueChanged(app, event)
            %value = app.holaCheckBox.Value;
            app.UIAxes.Visible = app.holaCheckBox.Value;
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelecciondedatosLabel = uilabel(app.UIFigure);
            app.SelecciondedatosLabel.Text = 'Seleccion de datos';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.AbrirButton = uibutton(app.UIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.TextArea = uitextarea(app.UIFigure);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Carpetas'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Carpetas';
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'Archivos en la carpeta Exports'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = 'Archivos en la carpeta Exports';
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.SeleccionarButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.TiempoSliderLabel = uilabel(app.UIFigure);
            app.TiempoSliderLabel.Text = 'Tiempo';
            app.TiempoSlider = uislider(app.UIFigure);
            app.TiempoSlider.ValueChangedFcn = createCallbackFcn(app, @TiempoSliderValueChanged, true);
            app.numeroEditField = uieditfield(app.UIFigure, 'numeric');
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.Text = 'Iniciar';
            app.holaCheckBox = uicheckbox(app.UIFigure);
            app.holaCheckBox.ValueChangedFcn = createCallbackFcn(app, @holaCheckBoxValueChanged, true);
            app.holaCheckBox.Text = 'hola';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3_1_mod1_pupil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end